classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        NumberofAntennasEditFieldLabel  matlab.ui.control.Label
        NumberofAntennasEditField       matlab.ui.control.NumericEditField
        ParametersLabel                 matlab.ui.control.Label
        ArrayShapeDropDownLabel         matlab.ui.control.Label
        ArrayShapeDropDown              matlab.ui.control.DropDown
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        MHzLabel                        matlab.ui.control.Label
        UITableTargets                  matlab.ui.control.Table
        TargetsLabel                    matlab.ui.control.Label
        ButtonTargetAdd                 matlab.ui.control.Button
        ButtonTargetRemove              matlab.ui.control.Button
        UITableNulls                    matlab.ui.control.Table
        NullsLabel                      matlab.ui.control.Label
        ButtonNullAdd                   matlab.ui.control.Button
        ButtonNullRemove                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_Map                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fc              % frequency of signal
        lambda          % wavelength of signal
        numAntennas     % number of antennas
        antenna         % currently selected antenna
        array           % antenna array
        az_null         % null azimuth (degrees)
        az_target       % target azimuth (degrees)
        ele_null        % null elevation (degrees)
        ele_target      % target elevation (degrees)
        UITableTargetsSelectedCells % for tracking cells to remove
        UITableNullsSelectedCells 
        map_fig         % Hidden Figure object for mapping
        map_ax          % Map Axes
        map_strokes     % Anything that was added to the map (for deletion)
        array_lat       % Latitude of Array
        array_lon       % Longitude of Array
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fc = 22e6;        % frequency of signal
            app.lambda = physconst('LightSpeed')/app.fc;     % wavelength of signal
            app.numAntennas = 8;    % number of antennas
            app.antenna = monopoleRadial('Height',app.lambda/4,'RadialLength',app.lambda/4,'Width',.1,'RadialWidth',.1,'NumRadials',4);     % currently selected antenna
            app.array = phased.ULA('NumElements',app.numAntennas,'Element', app.antenna, 'ElementSpacing', app.lambda/2);      % antenna array
            app.az_null = 0;     % null azimuth (degrees)
            app.az_target = 90;   % target azimuth (degrees)
            app.ele_null = 0;    % null elevation (degrees)
            app.ele_target = 0;  % target elevation (degrees)
            app.UITableTargets.Data = [90 0]; % default table values (degrees)
            app.UITableNulls.Data = [0 0]; 
            
            % Initialize 
            axis(app.UIAxes_Map,'equal');
            axis(app.UIAxes_Map,'off');
            app.map_fig = figure('visible','off');
            app.map_ax = axesm('MapProjection','mercator','MapLatLimit',[-75 80], ...
                                   'MapLonLimit', [90 300], 'Grid','off','Frame', 'on');
            setm(app.map_ax, 'meridianlabel', 'off', 'parallellabel', 'off')
            land = shaperead('landareas', 'UseGeoCoords', true);
            geoshow(app.map_ax, land, 'FaceColor', [0.5 0.7 0.5])
            app.array_lat = -1.5072;
            app.array_lon = 143.6436;
            plotm(app.array_lat, app.array_lon, 'bo');
            tightmap;
            copyobj(app.map_ax.Children, app.UIAxes_Map);  % Copy all of the axis' children to your app axis
            title(app.UIAxes_Map, '');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.numAntennas = app.NumberofAntennasEditField.Value;
            app.fc = app.FrequencyEditField.Value * 1e6;
            app.lambda = physconst('LightSpeed')/app.fc;
            app.az_null = app.UITableNulls.Data(:,1).';      % null azimuth (degrees)
            app.az_target = app.UITableTargets.Data(:,1).';   % target azimuth (degrees)
            app.ele_null = app.UITableNulls.Data(:,2).';    % null elevation (degrees)
            app.ele_target = app.UITableTargets.Data(:,2).';  % target elevation (degrees)
            
            % Update the Map
            %figure(app.map_fig);
            set(0, 'CurrentFigure', app.map_fig);
            delete(app.map_strokes);
            app.map_strokes = [];
            for i=length(app.az_target)
               [target_lat, target_lon] = track1(app.array_lat,app.array_lon,app.az_target(i), 50);
               app.map_strokes(end+1) = plotm(target_lat(end), target_lon(end), 'go');
               app.map_strokes(end+1) = linem([app.array_lat; target_lat(end)], [app.array_lon; target_lon(end)], 'g-');
            end
            for i=length(app.az_null)
               [null_lat, null_lon] = track1(app.array_lat,app.array_lon,app.az_null(i), 50);
               app.map_strokes(end+1) = plotm(null_lat(end), null_lon(end), 'ro');
               app.map_strokes(end+1) = linem([app.array_lat; null_lat(end)], [app.array_lon; null_lon(end)], 'r-');
            end
            cla(app.UIAxes_Map)
            copyobj(app.map_ax.Children, app.UIAxes_Map);  % Copy all of the axis' children to your app axis
            
            if strcmp(app.ArrayShapeDropDown.Value, 'ULA')
                app.array = phased.ULA('NumElements',app.numAntennas,'Element', app.antenna, 'ElementSpacing', app.lambda/2);
            end
            if strcmp(app.ArrayShapeDropDown.Value, 'UCA')
                app.array = phased.UCA('NumElements',app.numAntennas,'Element', app.antenna, 'Radius', app.lambda/2);
            end
            
            %clear out previous plots
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_4)
            
            % Generalized sidelobe canceller
            % Calculate the steering vector for null directions
            steervec = phased.SteeringVector('SensorArray',app.array);
            wn = steervec(app.fc, [app.az_null; app.ele_null]); %%% BROKEN FOR MULTIPLE NULLS
            
            % Calculate the steering vectors for lookout directions
            wd = steervec(app.fc, [app.az_target; app.ele_target]);
%             win = kaiser(app.numAntennas,4);
%             wd = win.*wd;
            
            % Compute the response of desired steering at null direction
            rn = wn'*wd/(wn'*wn);
            
            % Sidelobe canceler - remove the response at null direction
            w = wd-wn*rn;
            
            %% Plot azimuth cut 2D
            
            [plot_pattern, plot_az, plot_ele] = pattern(app.array,app.fc,-180:1:180,-90:1:90,'PropagationSpeed',physconst('LightSpeed'),'Type','powerdb',...
                'CoordinateSystem','polar','Weights',w);
            
            plot(app.UIAxes, plot_az, plot_pattern(ceil(numel(plot_ele)/2),:))
            title(app.UIAxes, 'Azimuth Cut (Elevation = 0.0\circ)')
            ylabel(app.UIAxes, 'Normalized Power (dB)')
            xlabel(app.UIAxes, 'Azimuth Angle (degrees)')
            hold(app.UIAxes, 'on')
            for i = 1:length(app.az_null)
                plot(app.UIAxes, [app.az_null(i) app.az_null(i)],[-1000 100],'r--','LineWidth',2)
            end
            for z = 1:length(app.az_target)
                plot(app.UIAxes, [app.az_target(z) app.az_target(z)],[-1000 100],'g--','LineWidth',2)
            end
            xlim(app.UIAxes, [-180 180]);
            ylim(app.UIAxes, [-100 10]);
            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'off')
            
            %% Plot elevation cut 2d
            
            plot(app.UIAxes_4, plot_ele, plot_pattern(:,floor(numel(plot_az)/2)))
            title(app.UIAxes_4, 'Elevation Cut (Azimuth = 0.0\circ)')
            ylabel(app.UIAxes_4, 'Normalized Power (dB)')
            xlabel(app.UIAxes_4, 'Elevation Angle (degrees)')
%             hold(app.UIAxes_4, 'on')
%             for i = 1:length(app.az_null)
%                 plot(app.UIAxes_4, [app.az_null(i) app.az_null(i)],[-1000 100],'r--','LineWidth',2)
%             end
%             for z = 1:length(app.az_target)
%                 plot(app.UIAxes_4, [app.az_target(z) app.az_target(z)],[-1000 100],'g--','LineWidth',2)
%             end
            xlim(app.UIAxes_4, [-90 90]);
            ylim(app.UIAxes_4, [-100 10]);
            grid(app.UIAxes_4, 'on')
            hold(app.UIAxes_4, 'off')
            
            
            %Plot 3D pattern
            
            mesh(app.UIAxes_3, plot_az, plot_ele, plot_pattern);
            hold(app.UIAxes_3, 'on')
            plot3(app.UIAxes_3, app.az_target, app.ele_target, plot_pattern(app.ele_target + 91, app.az_target + 181), 'o','Color','g','MarkerSize',10,'MarkerFaceColor','g')
            plot3(app.UIAxes_3, app.az_null, app.ele_null, max(plot_pattern(app.ele_null + 91, app.az_null + 181), -100), 'o','Color','r','MarkerSize',10,'MarkerFaceColor','r')
            hold(app.UIAxes_3, 'off')
            title(app.UIAxes_3, 'Rectangular 3D Plot')
            zlabel(app.UIAxes_3, 'Normalized Power (dB)')
            xlabel(app.UIAxes_3, 'Azimuth Angle (degrees)')
            ylabel(app.UIAxes_3, 'Elevation Angle (degrees)')
            xlim(app.UIAxes_3, [-180 180]);
            ylim(app.UIAxes_3, [-90 90]);
            zlim(app.UIAxes_3, [-100 10]);
            caxis(app.UIAxes_3, [-100 10]);
            grid(app.UIAxes_3, 'on')
            colorbar(app.UIAxes_3);
            pbaspect(app.UIAxes_3, [2 2 1])
            axis(app.UIAxes_3, 'vis3d')
            
            %Plot antenna array position
            
            positions = getElementPosition(app.array);
            antennaLine = [app.lambda/4 0 0; 0 0 0; 0 app.lambda/4 0; 0 0 0; -app.lambda/4 0 0; 0 0 0; 0 -app.lambda/4 0; 0 0 0; 0 0 app.lambda/4];
            
            hold(app.UIAxes_2, 'on')
            for antennaIndex = 1:app.numAntennas
                plot3(app.UIAxes_2, antennaLine(:,1) + positions(1,antennaIndex), antennaLine(:,2) + positions(2,antennaIndex), antennaLine(:,3) + positions(3,antennaIndex),'LineWidth', 3 ,'Color','b')
            end
            title(app.UIAxes_2, 'Array Layout Plot')
            zlabel(app.UIAxes_2, 'Z (m)')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            grid(app.UIAxes_2, 'on')
            hold(app.UIAxes_2, 'off')
            xlim(app.UIAxes_2, [(min(min(positions)) + min(min(antennaLine))) (max(max(positions)) + max(max(antennaLine)))])
            ylim(app.UIAxes_2, [(min(min(positions)) + min(min(antennaLine))) (max(max(positions)) + max(max(antennaLine)))])
            zlim(app.UIAxes_2, [0 (max(max(positions)) + max(max(antennaLine)))/2])
            %zlim(app.UIAxes_3, [-1 max(1 + antennaLine(:,3) + positions(3,antennaIndex))]);
            pbaspect(app.UIAxes_2, [2 2 1])
            view(app.UIAxes_2, [45 45]);
            axis(app.UIAxes_2, 'vis3d')


        end

        % Callback function
        function SliderValueChanging(app, event)
            
        end

        % Value changed function: ArrayShapeDropDown
        function ArrayShapeDropDownValueChanged(app, event)
            value = app.ArrayShapeDropDown.Value;
        end

        % Button pushed function: ButtonNullRemove
        function ButtonNullRemovePushed(app, event)
            if isempty(app.UITableNullsSelectedCells)
                app.UITableNulls.Data(end,:) = []; % Delete Row
            else
                app.UITableNulls.Data(app.UITableNullsSelectedCells(:,1),:) = []; % delete rows
            end
        end

        % Button pushed function: ButtonNullAdd
        function ButtonNullAddPushed(app, event)
            app.UITableNulls.Data(end+1,:) = 0; % Add Row of zeros
        end

        % Button pushed function: ButtonTargetRemove
        function ButtonTargetRemovePushed(app, event)
            if isempty(app.UITableTargetsSelectedCells)
                app.UITableTargets.Data(end,:) = []; % Delete Row
            else
                app.UITableTargets.Data(app.UITableTargetsSelectedCells(:,1),:) = []; % delete rows
            end
        end

        % Button pushed function: ButtonTargetAdd
        function ButtonTargetAddPushed(app, event)
            app.UITableTargets.Data(end+1,:) = 0; % Add Row of zeros
        end

        % Cell selection callback: UITableTargets
        function UITableTargetsCellSelection(app, event)
            app.UITableTargetsSelectedCells = event.Indices;
        end

        % Cell selection callback: UITableNulls
        function UITableNullsCellSelection(app, event)
            app.UITableNullsSelectedCells = event.Indices;         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofAntennasEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAntennasEditFieldLabel.Text = 'Number of Antennas';
            app.NumberofAntennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAntennasEditField.Limits = [2 8];
            app.NumberofAntennasEditField.RoundFractionalValues = 'on';
            app.NumberofAntennasEditField.Value = 8;
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = {'Parameters'; ''};
            app.ArrayShapeDropDownLabel = uilabel(app.UIFigure);
            app.ArrayShapeDropDownLabel.Text = 'Array Shape';
            app.ArrayShapeDropDown = uidropdown(app.UIFigure);
            app.ArrayShapeDropDown.Items = {'ULA', 'UCA'};
            app.ArrayShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ArrayShapeDropDownValueChanged, true);
            app.ArrayShapeDropDown.Value = 'ULA';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [5 22];
            app.FrequencyEditField.Value = 22;
            app.MHzLabel = uilabel(app.UIFigure);
            app.MHzLabel.Text = 'MHz';
            app.UITableTargets = uitable(app.UIFigure);
            app.UITableTargets.ColumnName = {'Azimuth'; 'Elevation'};
            app.UITableTargets.RowName = {};
            app.UITableTargets.ColumnEditable = true;
            app.UITableTargets.CellSelectionCallback = createCallbackFcn(app, @UITableTargetsCellSelection, true);
            app.TargetsLabel = uilabel(app.UIFigure);
            app.TargetsLabel.Text = 'Targets';
            app.ButtonTargetAdd = uibutton(app.UIFigure, 'push');
            app.ButtonTargetAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonTargetAddPushed, true);
            app.ButtonTargetAdd.Text = '+';
            app.ButtonTargetRemove = uibutton(app.UIFigure, 'push');
            app.ButtonTargetRemove.ButtonPushedFcn = createCallbackFcn(app, @ButtonTargetRemovePushed, true);
            app.ButtonTargetRemove.Text = '-';
            app.UITableNulls = uitable(app.UIFigure);
            app.UITableNulls.ColumnName = {'Azimuth'; 'Elevation'};
            app.UITableNulls.RowName = {};
            app.UITableNulls.ColumnEditable = true;
            app.UITableNulls.CellSelectionCallback = createCallbackFcn(app, @UITableNullsCellSelection, true);
            app.NullsLabel = uilabel(app.UIFigure);
            app.NullsLabel.Text = 'Nulls';
            app.ButtonNullAdd = uibutton(app.UIFigure, 'push');
            app.ButtonNullAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonNullAddPushed, true);
            app.ButtonNullAdd.Text = '+';
            app.ButtonNullRemove = uibutton(app.UIFigure, 'push');
            app.ButtonNullRemove.ButtonPushedFcn = createCallbackFcn(app, @ButtonNullRemovePushed, true);
            app.ButtonNullRemove.Text = '-';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_Map = uiaxes(app.UIFigure);
            title(app.UIAxes_Map, 'Title')
            xlabel(app.UIAxes_Map, 'X')
            ylabel(app.UIAxes_Map, 'Y')
            zlabel(app.UIAxes_Map, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end